classdef RehabRightFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LoginPanel                  matlab.ui.container.Panel
        LoginErrorLabel             matlab.ui.control.Label
        ShowLoginPassCheckBox       matlab.ui.control.CheckBox
        NewUserButton               matlab.ui.control.Button
        Image2                      matlab.ui.control.Image
        RehabRightLabel_2           matlab.ui.control.Label
        LoginButton                 matlab.ui.control.Button
        PasswordField               matlab.ui.control.EditField
        PasswordLabel               matlab.ui.control.Label
        UsernameField               matlab.ui.control.EditField
        UsernameEditFieldLabel      matlab.ui.control.Label
        SplashPanel                 matlab.ui.container.Panel
        Image                       matlab.ui.control.Image
        LoadingLabel                matlab.ui.control.Label
        RecoveryIsWithinGraspLabel  matlab.ui.control.Label
        LoadingGauge                matlab.ui.control.LinearGauge
        RehabRightLabel_3           matlab.ui.control.Label
        StatsPanel                  matlab.ui.container.Panel
        CloseStatsButton_2          matlab.ui.control.Button
        CloseStatsButton            matlab.ui.control.Button
        HighScoreLabel              matlab.ui.control.Label
        StatsAxes                   matlab.ui.control.UIAxes
        SignUpPanel                 matlab.ui.container.Panel
        CreateButton                matlab.ui.control.Button
        ShowSignUpPassCheckBox      matlab.ui.control.CheckBox
        BackButton                  matlab.ui.control.Button
        SignUpConfirmField          matlab.ui.control.EditField
        PasswordLabel_3             matlab.ui.control.Label
        Image2_2                    matlab.ui.control.Image
        RehabRightLabel_4           matlab.ui.control.Label
        SignUpPasswordField         matlab.ui.control.EditField
        PasswordLabel_2             matlab.ui.control.Label
        SignUpUsername              matlab.ui.control.EditField
        UsernameEditFieldLabel_2    matlab.ui.control.Label
        DashboardPanel              matlab.ui.container.Panel
        HeaderLabel                 matlab.ui.control.Label
        ManualCaptureButton_3       matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        ManualTab                   matlab.ui.container.Tab
        ManualCaptureButton         matlab.ui.control.Button
        AutoTab                     matlab.ui.container.Tab
        ViewStatsButton             matlab.ui.control.Button
        IntervalSpinner             matlab.ui.control.Spinner
        IntervalsSpinnerLabel       matlab.ui.control.Label
        AutoSwitch                  matlab.ui.control.Switch
        AutoModeSwitchLabel         matlab.ui.control.Label
        SoundTab                    matlab.ui.container.Tab
        VolumeGauge                 matlab.ui.control.NinetyDegreeGauge
        VolumeLevelGaugeLabel       matlab.ui.control.Label
        SoundSwitch                 matlab.ui.control.Switch
        MicTriggerLabel             matlab.ui.control.Label
        FeedbackLabel               matlab.ui.control.Label
        StatusLamp                  matlab.ui.control.Lamp
        StatusLabel                 matlab.ui.control.Label
        CameraAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % Hardware & AI
        m                % Mobile device connection
        cam              % Camera object
        net              % Your trained AI (myNet)
        inputSize        % Input size required by the AI
        audioRecorder    % Microphone
        speechObj        % For Text-to-Speech
        
        % Timers
        splashTimer
        autoTimer        % 30-second challenge timer
        soundTimer       % Mic trigger timer
        
        % Data
        currentUser
        userFile
        logFile
        historyFile                 % Path to the CSV file
        HistoryTable table          % Stores all past sessions
        CurrentHighScore = 0;
        StoredPassword = ' '
        StoredSignUpPassword = '';  % Hidden real password for signup
        StoredConfirmPassword = ''; % Hidden real confirmation password
        SessionData table
        
        % Challenge Variables
        RepCount = 0;    % Counts successful reps
        StartTime        % Tracks when the 30s started
        IsChallengeRunning = false;
        
        % Interaction State (Prevents spamming "Squeeze Harder" audio)
        LastSpokenTime = datetime('now');
    end

    methods (Access = private)
        
       function switchScreen(app, screenName)
            % 1. Hide EVERYTHING first
            app.SplashPanel.Visible = 'off';
            app.LoginPanel.Visible = 'off';
            app.DashboardPanel.Visible = 'off';
            app.StatsPanel.Visible = 'off';  
            app.SignUpPanel.Visible = 'off';
            
            % 2. Show the requested screen
            switch screenName
                case 'Splash'
                    app.SplashPanel.Visible = 'on';
                case 'Login'
                    app.LoginPanel.Visible = 'on';
                    app.PasswordField.Value = ''; 
                case 'Dashboard'
                    app.DashboardPanel.Visible = 'on';
                    app.DashboardPanel.Enable = 'on'; 
            end
       end
       % Helper: Update the Graph and Labels
        % Helper: Update the Graph and Labels
        function updateStatsUI(app)
            % 1. FILTER DATA (New Logic)
            % Find rows where the 'User' column matches the currently logged in user
            if isempty(app.HistoryTable)
                userHistory = [];
            else
                % strcmp returns true/false for each row
                userRows = strcmp(string(app.HistoryTable.User), string(app.currentUser));
                userHistory = app.HistoryTable(userRows, :);
            end
            
            % 2. Update High Score Label (Based ONLY on user data)
            if isempty(userHistory)
                currentBest = 0;
            else
                currentBest = max(userHistory.Score);
            end
            app.HighScoreLabel.Text = sprintf('High Score: \n%d Reps', currentBest);
            
            % 3. Setup Graph Colors
            app.StatsAxes.Color = [0.9490 0.9529 0.9569];
            app.StatsAxes.XColor = [0.0000 0.1529 0.4627];
            app.StatsAxes.YColor = [0.0000 0.1529 0.4627];
            app.StatsAxes.GridColor = [0.0000 0.1529 0.4627];
            
            % Clear any old plots so they don't overlap
            cla(app.StatsAxes);
            
            if isempty(userHistory)
                title(app.StatsAxes, 'No Sessions Yet', 'Color', 'k');
                return;
            end
            
            % 4. Plot ONLY the filtered 'userHistory'
            plot(app.StatsAxes, userHistory.Date, userHistory.Score, ...
                '-o', 'LineWidth', 2, 'Color', [0.30,0.18,0.45], ... 
                'MarkerFaceColor', [0.30,0.18,0.45]);               
                
            title(app.StatsAxes, 'My Rehab Progress', 'Color', 'k');
            xlabel(app.StatsAxes, 'Date', 'Color', 'k');
            ylabel(app.StatsAxes, 'Reps', 'Color', 'k');
            grid(app.StatsAxes, 'on');
        end
        
        % Helper: Save Results (Call this when timer finishes!)
        function saveSessionResults(app)
            % Create new entry
            timestamp = datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss');
            newEntry = table(timestamp, string(app.currentUser), app.RepCount, ...
                'VariableNames', {'Date', 'User', 'Score'});
            
            % Add to table and Save to CSV
            if isempty(app.HistoryTable)
                app.HistoryTable = newEntry;
            else
                app.HistoryTable = [app.HistoryTable; newEntry];
            end
            writetable(app.HistoryTable, app.historyFile);
            
            % Check for new High Score
            if app.RepCount > app.CurrentHighScore
                app.CurrentHighScore = app.RepCount;
                uialert(app.UIFigure, 'New High Score!', 'Congratulations');
            end
        end
        
       function moveLoadingBar(app)
            try
                % 1. Get current value
                current = app.LoadingGauge.Value;
                
                % 2. Move it
                newValue = current + 5; 
                
                % 3. Check if finished
                if newValue < 100
                    app.LoadingGauge.Value = newValue;
                    
                    % Update text status
                    if newValue < 40
                        app.LoadingLabel.Text = 'Initializing Modules...';
                    elseif newValue < 80
                        app.LoadingLabel.Text = 'Connecting to Database...';
                    else
                        app.LoadingLabel.Text = 'Starting Application...';
                    end
                else
                    % 4. FINISHED: Stop using splashTimer
                    stop(app.splashTimer);
                    delete(app.splashTimer); 
                    
                    % Force full bar
                    app.LoadingGauge.Value = 100;
                    pause(0.2); 
                    
                    % Switch Screens
                    app.SplashPanel.Visible = 'off';
                    app.LoginPanel.Visible = 'on';
                end
            catch
                % Safety Clean up using splashTimer
                try stop(app.splashTimer); delete(app.splashTimer); catch; end
                app.SplashPanel.Visible = 'off';
                app.LoginPanel.Visible = 'on';
            end
        end
        
        function performCapture(app, sourceMode)
            % 1. Visual Status
            app.StatusLamp.Color = 'blue'; 
            app.StatusLabel.Text = 'Scanning...';
            drawnow;
            
            try
                % 2. Check Camera
                if isempty(app.cam)
                    app.cam = camera(app.m, 'back');
                end
                
                % 3. Capture & Process
                img = snapshot(app.cam, 'immediate'); 
                imshow(img, 'Parent', app.CameraAxes);
                
                imgResized = imresize(img, app.inputSize(1:2));
                [label, scores] = classify(app.net, imgResized);
                
                result = char(label);
                confidence = max(scores);
                
                % 4. INTELLIGENT FEEDBACK
                
                % Case A: No Hand / Low Confidence
                if confidence < 0.6 || strcmpi(result, 'Background')
                    feedbackMsg = 'Place Hand in Frame';
                    color = [0.6 0.6 0.6]; % Grey
                    app.StatusLamp.Color = 'red';
                    
                % Case B: Hand Detected but Open
                elseif strcmpi(result, 'Hand') || strcmpi(result, 'Open')
                    feedbackMsg = 'Squeeze Harder';
                    color = [1 0.6 0]; % Orange
                    app.StatusLamp.Color = 'yellow';
                    
                    % Prevent audio spam (only speak every 3 seconds)
                    if seconds(datetime('now') - app.LastSpokenTime) > 3
                        try Speak(app.speechObj, 'Squeeze harder'); catch; end
                        app.LastSpokenTime = datetime('now');
                    end
                    
                % Case C: Good Rep
                elseif strcmpi(result, 'Fist') || strcmpi(result, 'Closed')
                    feedbackMsg = 'Good Rep!';
                    color = [0 1 0]; % Green
                    app.StatusLamp.Color = 'green';
                    
                    % Only count in Auto Mode
                    if strcmp(sourceMode, 'Auto')
                        app.RepCount = app.RepCount + 1;
                        try Speak(app.speechObj, num2str(app.RepCount)); catch; end
                    end
                end
                
                % 5. Update UI
                app.FeedbackLabel.Text = feedbackMsg;
                app.FeedbackLabel.FontColor = color;
                
                if strcmp(sourceMode, 'Auto')
                    app.StatusLabel.Text = sprintf('Reps: %d', app.RepCount);
                else
                    app.StatusLabel.Text = result; 
                end
                
            catch err
                app.StatusLamp.Color = 'red';
                app.FeedbackLabel.Text = 'Camera Error';
            end
        end
        
        % NEW METHOD: Checks microphone volume for the trigger
        function checkMicLevel(app)
            try
                % Read audio samples
                audioData = app.audioReader();
                
                % Calculate loudness (RMS) and scale it up
                volume = rms(audioData) * 500; 
                
                % Update Gauge (Limit to 100 max)
                app.VolumeGauge.Value = min(volume, 100);
                
                % Check Trigger (If Sound Switch is ON and Volume > 50)
                if strcmp(app.SoundSwitch.Value, 'On') && volume > 50
                    % Prevent double triggering by pausing timer briefly
                    stop(app.soundTimer); 
                    performCapture(app, 'Voice');
                    pause(1); 
                    start(app.soundTimer);
                end
            catch
                % Ignore audio errors if mic is busy
            end
        end
            
        
        % NEW METHOD: Handles the 30-second loop
        function runAutoChallenge(app)
            % 1. Calculate Time Remaining
            elapsedTime = seconds(datetime('now') - app.StartTime);
            remainingTime = 30 - elapsedTime;
            
            if remainingTime <= 0
                % --- TIME IS UP ---
                stop(app.autoTimer);
                app.IsChallengeRunning = false;
                app.AutoSwitch.Value = 'Off';
                
                % Final Announcement
                finalMsg = sprintf('Time is up! You completed %d reps.', app.RepCount);
                uialert(app.UIFigure, finalMsg, 'Challenge Complete');
                try Speak(app.speechObj, finalMsg); catch; end
                
                app.StatusLabel.Text = 'Done';
                app.AutoModeSwitchLabel.Text = 'Auto Mode';
                
                % SAVE RESULTS (Call the new method)
                saveSessionResults(app);
            else
                % --- CONTINUE CHALLENGE ---
                app.AutoModeSwitchLabel.Text = sprintf('Time: %.0fs', remainingTime);
                performCapture(app, 'Auto');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. Setup UI & Paths
            movegui(app.UIFigure, 'center');
            
            % Ensure the Stats Panel is hidden and Dashboard is visible at start
            app.StatsPanel.Visible = 'off'; 
            app.DashboardPanel.Visible = 'off';
            app.LoginPanel.Visible = 'off';
            app.SplashPanel.Visible = 'on';
            
            % --- RELATIVE PATH SETUP (The "Best in Year" Fix) ---
            % This creates a folder in the user's standard MATLAB Documents folder.
            % It works on ANY computer (Examiner's, Uni Lab, etc.)
            folder = fullfile(userpath, 'RehabData');
            if ~isfolder(folder), mkdir(folder); end
            
            % 1a. Define History File Path
            app.historyFile = fullfile(folder, 'UserHistory.csv');
            
            % 1b. Define User Database Path
            app.userFile = fullfile(folder, 'UserDatabase.csv');
            
            % Check if User Database exists; if not, create it with a default admin
            if ~isfile(app.userFile)
                % Create a table with the default admin account
                % We use string arrays ("") to ensure text format
                T = table("admin", "1234", 'VariableNames', {'Username', 'Password'});
                writetable(T, app.userFile);
            end
            % ----------------------------------------------------
            
            % 2. Load History & High Score
            try
                if isfile(app.historyFile)
                    app.HistoryTable = readtable(app.historyFile);
                    if ~isempty(app.HistoryTable)
                        app.CurrentHighScore = max(app.HistoryTable.Score);
                    end
                else
                    app.HistoryTable = table([], [], [], 'VariableNames', {'Date', 'User', 'Score'});
                end
            catch
                app.HistoryTable = table([], [], [], 'VariableNames', {'Date', 'User', 'Score'});
            end
            
            % 3. LOAD THE AI MODEL
            try
                % Attempt to load user trained network
                data = load('myNet.mat'); 
                fields = fieldnames(data);
                app.net = data.(fields{1}); 
                app.inputSize = app.net.Layers(1).InputSize; 
            catch
                % Fallback to AlexNet if myNet.mat is missing
                try
                    app.net = alexnet; 
                    app.inputSize = [227 227 3];
                catch
                    uialert(app.UIFigure, 'No AI Models found. Please install AlexNet.', 'Error');
                end
            end
            
            % 4. Hardware Setup
            try
                app.m = mobiledev;  
                app.cam = camera(app.m, 'back'); 
                
                % Enable Microphone
                app.m.AudioSensorEnabled = 1; 
                app.m.Logging = 1; 
                
                NET.addAssembly('System.Speech');
                app.speechObj = System.Speech.Synthesis.SpeechSynthesizer;
            catch
            end
            
            % 5. Setup Timers & Start
            app.splashTimer = timer('ExecutionMode', 'fixedRate', 'Period', 0.05, ...
                'TimerFcn', @(~,~) moveLoadingBar(app));
            app.autoTimer = timer('ExecutionMode','fixedRate', 'Period', 1.0, ...
                'TimerFcn', @(~,~) runAutoChallenge(app));
            app.soundTimer = timer('ExecutionMode','fixedRate', 'Period', 0.1, ...
                'TimerFcn', @(~,~) checkMicLevel(app));
            
            start(app.splashTimer);
        
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
           % 1. Get input (Remove accidental spaces)
            user = strip(app.UsernameField.Value);
            pass = strip(app.StoredPassword);        
            
            % 2. Define path (Relative path for Examiners)
            folder = fullfile(userpath, 'RehabData');
            csvFile = fullfile(folder, 'UserDatabase.csv');
            
            loginSuccess = false; 
            
            % ---------------------------------------------------------
            % CHECK 1: REAL USER DATABASE
            % ---------------------------------------------------------
            try
                if isfile(csvFile)
                    opts = detectImportOptions(csvFile);
                    opts = setvartype(opts, {'Username','Password'}, 'string');
                    T = readtable(csvFile, opts);
                    
                    % Check match (Case insensitive for user, sensitive for pass)
                    match = strcmpi(strip(T.Username), user) & ...
                            strcmp(strip(T.Password), pass);
                    
                    if any(match)
                        loginSuccess = true;
                        app.currentUser = user;
                    end
                end
            catch
                % If file is missing or corrupt, we just continue to Admin check
            end
            
            % ---------------------------------------------------------
            % CHECK 2: ADMIN BACKDOOR (Specific Credentials Only)
            % ---------------------------------------------------------
            if ~loginSuccess
                if strcmpi(user, 'admin') && strcmp(pass, '1234')
                    app.currentUser = 'admin'; 
                    loginSuccess = true;
                end
            end
            
            % FINAL DECISION
            
            if loginSuccess
                % SUCCESS: Clear errors, reset password field, and enter
                app.LoginErrorLabel.Text = '';
                app.StoredPassword = ''; 
                app.PasswordField.Value = '';
                
                switchScreen(app, 'Dashboard');
                app.HeaderLabel.Text = sprintf('Welcome, %s', app.currentUser);
            else
                % FAILURE: Show error message
                app.LoginErrorLabel.Text = 'Invalid Username or Password';
                
                % Clear the typed password so they can try again
                app.StoredPassword = '';
                app.PasswordField.Value = '';
            end
        
        end

        % Button pushed function: ManualCaptureButton
        function ManualCaptureButtonPushed(app, event)
            performCapture(app, 'Manual');
        end

        % Value changed function: AutoSwitch
        function AutoSwitchValueChanged(app, event)
           if strcmp(app.AutoSwitch.Value, 'On')
                % 1. Initialize Challenge Data
                app.IsChallengeRunning = true;
                app.RepCount = 0;
                app.StartTime = datetime('now');
                
                % 2. Feedback
                app.StatusLabel.Text = 'Starting...';
                try
                    Speak(app.speechObj, 'Starting 30 second challenge');
                catch
                end
                
                % 3. Start the Timer
                % Ensure period is 1 second for the countdown
                app.autoTimer.Period = 1.0; 
                start(app.autoTimer);
            else
                % User turned it off manually
                stop(app.autoTimer);
                app.IsChallengeRunning = false;
                app.StatusLabel.Text = 'Cancelled';
                app.AutoModeSwitchLabel.Text = 'Auto Mode';
           
           end
        end

        % Value changing function: PasswordField
        function PasswordFieldValueChanging(app, event)
            % If "Show Password" is ON, treat as normal text field
            if app.ShowLoginPassCheckBox.Value
                app.StoredPassword = event.Value;
                return; 
            end

            % --- EXISTING MASKING LOGIC ---
            currentInput = event.Value;
            lenDiff = length(currentInput) - length(app.StoredPassword);
            
            if lenDiff > 0
                newChars = currentInput(end-lenDiff+1:end);
                app.StoredPassword = [app.StoredPassword, newChars];
            elseif lenDiff < 0
                if length(app.StoredPassword) >= abs(lenDiff)
                    app.StoredPassword = app.StoredPassword(1:end+lenDiff);
                else
                    app.StoredPassword = ''; 
                end
            end
            app.PasswordField.Value = repmat('*', 1, length(app.StoredPassword));
        
        end

        % Button pushed function: ViewStatsButton
        function ViewStatsButtonPushed(app, event)
           % 1. Refresh the data
            updateStatsUI(app);
            
            % 2. Bring panel to front (CRITICAL FIX)
            uistack(app.StatsPanel, 'top'); 
            
            % 3. Show the Panel
            app.StatsPanel.Visible = 'on';
            
            % 4. Disable Dashboard
            app.DashboardPanel.Enable = 'off';
        end

        % Button pushed function: CloseStatsButton
        function CloseStatsButtonPushed(app, event)
            % 1. Hide the Panel
            app.StatsPanel.Visible = 'off';
            
            % 2. Re-enable Dashboard
            app.DashboardPanel.Enable = 'on';
        end

        % Button pushed function: ManualCaptureButton_3
        function ManualCaptureButton_3Pushed(app, event)
           
            % Stop any running timers
            stop(app.autoTimer);
            stop(app.soundTimer);
            app.AutoSwitch.Value = 'Off';
            app.SoundSwitch.Value = 'Off';
            
            % Return to Login
            switchScreen(app, 'Login');
            app.HeaderLabel.Text = 'Welcome';
        
        end

        % Value changed function: SoundSwitch
        function SoundSwitchValueChanged(app, event)
            
            
        end

        % Button pushed function: NewUserButton
        function NewUserButtonPushed(app, event)
            app.LoginPanel.Visible = 'off';
            app.SignUpPanel.Visible = 'on';
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            user = app.SignUpUsername.Value;
            pass = app.StoredSignUpPassword;
            conf = app.StoredConfirmPassword;
            
            % 1. Validation Checks
            if isempty(user) || isempty(pass)
                uialert(app.UIFigure, 'Please fill all fields.', 'Error');
                return;
            end
            
            if ~strcmp(pass, conf)
                uialert(app.UIFigure, 'Passwords do not match!', 'Mismatch');
                return;
            end
            
            % 2. Check for Duplicates
            if isfile(app.userFile)
                opts = detectImportOptions(app.userFile);
                opts = setvartype(opts, {'Username', 'Password'}, 'string');
                T = readtable(app.userFile, opts);
                
                if any(strcmpi(T.Username, user))
                    uialert(app.UIFigure, 'Username already taken.', 'Error');
                    return;
                end
            else
                T = table; % Create new if missing
            end
            
            % 3. Save New User
            newRow = table(string(user), string(pass), 'VariableNames', {'Username', 'Password'});
            
            if isempty(T)
                T = newRow;
            else
                T = [T; newRow];
            end
            
            writetable(T, app.userFile);
            
            % 4. Success
            uialert(app.UIFigure, 'Account Created!', 'Success');
            app.SignUpPanel.Visible = 'off';
            app.LoginPanel.Visible = 'on';
        
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.SignUpPanel.Visible = 'off';
            app.LoginPanel.Visible = 'on';
        end

        % Value changed function: ShowLoginPassCheckBox
        function ShowLoginPassCheckBoxValueChanged(app, event)
            if app.ShowLoginPassCheckBox.Value
                % Show the real password
                app.PasswordField.Value = app.StoredPassword;
            else
                % Hide it again
                app.PasswordField.Value = repmat('*', 1, length(app.StoredPassword));
            end
            
        end

        % Value changing function: SignUpPasswordField
        function SignUpPasswordFieldValueChanging(app, event)
            if app.ShowSignUpPassCheckBox.Value
                app.StoredSignUpPassword = event.Value;
                return;
            end
            
            % Standard Masking Logic
            currentInput = event.Value;
            lenDiff = length(currentInput) - length(app.StoredSignUpPassword);
            if lenDiff > 0
                app.StoredSignUpPassword = [app.StoredSignUpPassword, currentInput(end-lenDiff+1:end)];
            elseif lenDiff < 0
                app.StoredSignUpPassword = app.StoredSignUpPassword(1:end+lenDiff);
            end
            app.SignUpPasswordField.Value = repmat('*', 1, length(app.StoredSignUpPassword));
            
        end

        % Value changing function: SignUpConfirmField
        function SignUpConfirmFieldValueChanging(app, event)
            if app.ShowSignUpPassCheckBox.Value
                app.StoredConfirmPassword = event.Value;
                return;
            end
            
            currentInput = event.Value;
            lenDiff = length(currentInput) - length(app.StoredConfirmPassword);
            if lenDiff > 0
                app.StoredConfirmPassword = [app.StoredConfirmPassword, currentInput(end-lenDiff+1:end)];
            elseif lenDiff < 0
                app.StoredConfirmPassword = app.StoredConfirmPassword(1:end+lenDiff);
            end
            app.SignUpConfirmField.Value = repmat('*', 1, length(app.StoredConfirmPassword));
        end

        % Value changed function: ShowSignUpPassCheckBox
        function ShowSignUpPassCheckBoxValueChanged(app, event)
            if app.ShowSignUpPassCheckBox.Value
                app.SignUpPasswordField.Value = app.StoredSignUpPassword;
                app.SignUpConfirmField.Value = app.StoredConfirmPassword;
            else
                app.SignUpPasswordField.Value = repmat('*', 1, length(app.StoredSignUpPassword));
                app.SignUpConfirmField.Value = repmat('*', 1, length(app.StoredConfirmPassword));
            end
        end

        % Button pushed function: CloseStatsButton_2
        function CloseStatsButton_2Pushed(app, event)
            
            % 0. Check Data
            if isempty(app.HistoryTable)
                uialert(app.UIFigure, 'No data available to generate report.', 'Error');
                return;
            end
            
            % Filter for Current User
            userRows = strcmp(string(app.HistoryTable.User), string(app.currentUser));
            userData = app.HistoryTable(userRows, :);
            
            if isempty(userData)
                uialert(app.UIFigure, 'No sessions found for this user.', 'No Data');
                return;
            end
            
            % 1. CALCULATE STATISTICS
            scores = userData.Score;
            dates = userData.Date;
            
            [maxScore, maxIdx] = max(scores);
            [minScore, minIdx] = min(scores);
            avgScore = mean(scores);
            medScore = median(scores);
            stdDev = std(scores);
            
            % Handle Mode (if multiple modes, take first)
            modeVal = mode(scores);
            if length(modeVal) > 1,  modeVal = modeVal(1); end
            
            % 2. SETUP FILES (Relative Path)
            folder = fullfile(userpath, 'RehabReports');
            if ~isfolder(folder), mkdir(folder); end
            
            timestamp = datestr(now, 'dd-mmm_HH-MM');
            reportName = sprintf('Report_%s_%s.html', app.currentUser, timestamp);
            reportPath = fullfile(folder, reportName);
            imgName = sprintf('Graph_%s_%s.png', app.currentUser, timestamp);
            imgPath = fullfile(folder, imgName);
            
            % 3. SAVE GRAPH IMAGE
            try
                exportgraphics(app.StatsAxes, imgPath, 'Resolution', 150);
            catch
                % Fallback for older MATLAB
                f_temp = figure('Visible','off');
                copyobj(app.StatsAxes, f_temp);
                saveas(f_temp, imgPath);
                close(f_temp);
            end
            
            % 4. GENERATE HTML CONTENT
            fid = fopen(reportPath, 'w');
            
            % CSS Styling for Professional Look
            fprintf(fid, '<html><head><style>');
            fprintf(fid, 'body { font-family: Arial, sans-serif; background-color: #f4f4f9; padding: 40px; }');
            fprintf(fid, '.container { background: white; padding: 30px; border-radius: 10px; box-shadow: 0 0 10px rgba(0,0,0,0.1); max-width: 800px; margin: auto; }');
            fprintf(fid, 'h1 { color: #4b0082; border-bottom: 2px solid #4b0082; padding-bottom: 10px; }');
            fprintf(fid, 'table { width: 100%%; border-collapse: collapse; margin-top: 20px; }');
            fprintf(fid, 'th, td { padding: 12px; border-bottom: 1px solid #ddd; text-align: left; }');
            fprintf(fid, 'th { background-color: #4b0082; color: white; }');
            fprintf(fid, '.trend { font-weight: bold; padding: 10px; margin-top: 20px; background: #e8eaf6; border-left: 5px solid #4b0082; }');
            fprintf(fid, '</style></head><body>');
            
            fprintf(fid, '<div class="container">');
            
            % Header
            fprintf(fid, '<h1>Rehabilitation Progress Report</h1>');
            fprintf(fid, '<p><strong>Patient:</strong> %s<br>', app.currentUser);
            fprintf(fid, '<strong>Date Generated:</strong> %s</p>', datestr(now));
            
            % Statistics Table
            fprintf(fid, '<h3>Performance Metrics</h3>');
            fprintf(fid, '<table>');
            fprintf(fid, '<tr><th>Metric</th><th>Value</th><th>Notes</th></tr>');
            fprintf(fid, '<tr><td>Total Sessions</td><td>%d</td><td>Total attempts recorded</td></tr>', length(scores));
            fprintf(fid, '<tr><td>Personal Best</td><td>%d Reps</td><td>Achieved on %s</td></tr>', maxScore, datestr(dates(maxIdx)));
            fprintf(fid, '<tr><td>Lowest Score</td><td>%d Reps</td><td>Recorded on %s</td></tr>', minScore, datestr(dates(minIdx)));
            fprintf(fid, '<tr><td>Average Score</td><td>%.1f</td><td>Mean performance</td></tr>', avgScore);
            fprintf(fid, '<tr><td>Median Score</td><td>%.1f</td><td>Typical performance range</td></tr>', medScore);
            fprintf(fid, '<tr><td>Consistency (Std Dev)</td><td>%.2f</td><td>Lower is more consistent</td></tr>', stdDev);
            fprintf(fid, '</table>');
            
            % Graph
            fprintf(fid, '<h3>Visual Progress</h3>');
            fprintf(fid, '<img src="%s" style="width:100%%; border:1px solid #ddd;">', imgName);
            
            % Trend Analysis
            if scores(end) > scores(1)
                trend = 'Improving'; color = 'green';
            elseif scores(end) < scores(1)
                trend = 'Declining'; color = 'red';
            else
                trend = 'Stable'; color = 'black';
            end
            
            fprintf(fid, '<div class="trend" style="color:%s;">', color);
            fprintf(fid, 'Trend Analysis: Patient performance is currently %s.', trend);
            fprintf(fid, '</div>');
            
            fprintf(fid, '</div></body></html>');
            fclose(fid);
            
            % 5. OPEN REPORT AUTOMATICALLY
            web(reportPath); 
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DashboardPanel = uipanel(app.UIFigure);
            app.CameraAxes = uiaxes(app.DashboardPanel);
            zlabel(app.CameraAxes, 'Z')
            app.CameraAxes.XTick = [];
            app.CameraAxes.XTickLabel = '';
            app.CameraAxes.YTick = [];
            app.CameraAxes.YTickLabel = '';
            app.StatusLabel = uilabel(app.DashboardPanel);
            app.StatusLabel.Text = 'Status:';
            app.StatusLamp = uilamp(app.DashboardPanel);
            app.FeedbackLabel = uilabel(app.DashboardPanel);
            app.FeedbackLabel.Text = 'Good!';
            app.TabGroup = uitabgroup(app.DashboardPanel);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.ManualCaptureButton = uibutton(app.ManualTab, 'push');
            app.ManualCaptureButton.ButtonPushedFcn = createCallbackFcn(app, @ManualCaptureButtonPushed, true);
            app.ManualCaptureButton.Text = 'Capture';
            app.AutoTab = uitab(app.TabGroup);
            app.AutoTab.Title = 'Auto';
            app.AutoModeSwitchLabel = uilabel(app.AutoTab);
            app.AutoModeSwitchLabel.Text = 'Auto Mode';
            app.AutoSwitch = uiswitch(app.AutoTab, 'slider');
            app.AutoSwitch.ValueChangedFcn = createCallbackFcn(app, @AutoSwitchValueChanged, true);
            app.IntervalsSpinnerLabel = uilabel(app.AutoTab);
            app.IntervalsSpinnerLabel.Text = 'Interval (s)';
            app.IntervalSpinner = uispinner(app.AutoTab);
            app.IntervalSpinner.Value = 0.25;
            app.ViewStatsButton = uibutton(app.AutoTab, 'push');
            app.ViewStatsButton.ButtonPushedFcn = createCallbackFcn(app, @ViewStatsButtonPushed, true);
            app.ViewStatsButton.Text = 'View Data';
            app.SoundTab = uitab(app.TabGroup);
            app.SoundTab.Title = 'Sound';
            app.MicTriggerLabel = uilabel(app.SoundTab);
            app.MicTriggerLabel.Text = 'Mic Trigger';
            app.SoundSwitch = uiswitch(app.SoundTab, 'slider');
            app.SoundSwitch.ValueChangedFcn = createCallbackFcn(app, @SoundSwitchValueChanged, true);
            app.VolumeLevelGaugeLabel = uilabel(app.SoundTab);
            app.VolumeLevelGaugeLabel.Text = 'Volume Level';
            app.VolumeGauge = uigauge(app.SoundTab, 'ninetydegree');
            app.ManualCaptureButton_3 = uibutton(app.DashboardPanel, 'push');
            app.ManualCaptureButton_3.ButtonPushedFcn = createCallbackFcn(app, @ManualCaptureButton_3Pushed, true);
            app.ManualCaptureButton_3.Text = 'Log Out';
            app.HeaderLabel = uilabel(app.DashboardPanel);
            app.HeaderLabel.Text = 'Welcome';
            app.SignUpPanel = uipanel(app.UIFigure);
            app.SignUpPanel.Visible = 'off';
            app.UsernameEditFieldLabel_2 = uilabel(app.SignUpPanel);
            app.UsernameEditFieldLabel_2.Text = 'Username:';
            app.SignUpUsername = uieditfield(app.SignUpPanel, 'text');
            app.PasswordLabel_2 = uilabel(app.SignUpPanel);
            app.PasswordLabel_2.Text = 'Password:';
            app.SignUpPasswordField = uieditfield(app.SignUpPanel, 'text');
            app.SignUpPasswordField.ValueChangingFcn = createCallbackFcn(app, @SignUpPasswordFieldValueChanging, true);
            app.RehabRightLabel_4 = uilabel(app.SignUpPanel);
            app.RehabRightLabel_4.Text = 'RehabRight';
            app.Image2_2 = uiimage(app.SignUpPanel);
            app.Image2_2.ImageSource = 'Screenshot 2026-02-11 164935.png';
            app.PasswordLabel_3 = uilabel(app.SignUpPanel);
            app.PasswordLabel_3.Text = 'Confirm';
            app.SignUpConfirmField = uieditfield(app.SignUpPanel, 'text');
            app.SignUpConfirmField.ValueChangingFcn = createCallbackFcn(app, @SignUpConfirmFieldValueChanging, true);
            app.BackButton = uibutton(app.SignUpPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ShowSignUpPassCheckBox = uicheckbox(app.SignUpPanel);
            app.ShowSignUpPassCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowSignUpPassCheckBoxValueChanged, true);
            app.ShowSignUpPassCheckBox.Text = 'Reveal';
            app.CreateButton = uibutton(app.SignUpPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.StatsPanel = uipanel(app.UIFigure);
            app.StatsAxes = uiaxes(app.StatsPanel);
            title(app.StatsAxes, 'Session History')
            ylabel(app.StatsAxes, 'Total Reps')
            app.HighScoreLabel = uilabel(app.StatsPanel);
            app.HighScoreLabel.Text = 'High Score: ';
            app.CloseStatsButton = uibutton(app.StatsPanel, 'push');
            app.CloseStatsButton.ButtonPushedFcn = createCallbackFcn(app, @CloseStatsButtonPushed, true);
            app.CloseStatsButton.Text = 'Exit ';
            app.CloseStatsButton_2 = uibutton(app.StatsPanel, 'push');
            app.CloseStatsButton_2.ButtonPushedFcn = createCallbackFcn(app, @CloseStatsButton_2Pushed, true);
            app.CloseStatsButton_2.Text = 'Generate PDF';
            app.SplashPanel = uipanel(app.UIFigure);
            app.RehabRightLabel_3 = uilabel(app.SplashPanel);
            app.RehabRightLabel_3.Text = 'RehabRight';
            app.LoadingGauge = uigauge(app.SplashPanel, 'linear');
            app.LoadingGauge.MajorTickLabels = {'0', '3', '6', '9', '12', '15', '18', '21', '24', '27', '30', '33', '36', '39', '42', '45', '48', '51', '54', '57', '60', '63', '66', '69', '72', '75', '78', '81', '84', '87', '90', '93', '96', '100'};
            app.LoadingGauge.MinorTicks = [0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10 10.5 11 11.5 12 12.5 13 13.5 14 14.5];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
            app.RecoveryIsWithinGraspLabel = uilabel(app.SplashPanel);
            app.RecoveryIsWithinGraspLabel.Text = 'Recovery Is Within Grasp';
            app.LoadingLabel = uilabel(app.SplashPanel);
            app.LoadingLabel.Text = 'Loading...';
            app.Image = uiimage(app.SplashPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'qqee6c8ds1rmt0cw926vqt99xm.png');
            app.LoginPanel = uipanel(app.UIFigure);
            app.UsernameEditFieldLabel = uilabel(app.LoginPanel);
            app.UsernameEditFieldLabel.Text = 'Username:';
            app.UsernameField = uieditfield(app.LoginPanel, 'text');
            app.PasswordLabel = uilabel(app.LoginPanel);
            app.PasswordLabel.Text = 'Password:';
            app.PasswordField = uieditfield(app.LoginPanel, 'text');
            app.PasswordField.ValueChangingFcn = createCallbackFcn(app, @PasswordFieldValueChanging, true);
            app.LoginButton = uibutton(app.LoginPanel, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.RehabRightLabel_2 = uilabel(app.LoginPanel);
            app.RehabRightLabel_2.Text = 'RehabRight';
            app.Image2 = uiimage(app.LoginPanel);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2026-02-11 164935.png');
            app.NewUserButton = uibutton(app.LoginPanel, 'push');
            app.NewUserButton.ButtonPushedFcn = createCallbackFcn(app, @NewUserButtonPushed, true);
            app.NewUserButton.Text = 'New User?';
            app.ShowLoginPassCheckBox = uicheckbox(app.LoginPanel);
            app.ShowLoginPassCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowLoginPassCheckBoxValueChanged, true);
            app.ShowLoginPassCheckBox.Text = 'Reveal';
            app.LoginErrorLabel = uilabel(app.LoginPanel);
            app.LoginErrorLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RehabRightFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end